classdef apParcial < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ShazamAlakazamLabel     matlab.ui.control.Label
        AlakazameaButton        matlab.ui.control.Button
        ShazameaEditField       matlab.ui.control.EditField
        ShazameaEditFieldLabel  matlab.ui.control.Label
        PontucancionButton      matlab.ui.control.Button
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cancionInput

        dbDirectory
        cancionesDB

        desfaseTiempo
        cancionMatch

    end
    
    methods (Access = private)
        function setDatabase(app) %TEMPORAL
            app.dbDirectory = 'superBaseDeDatosSuperSeguraConSuperDatos'
            dbInfo = dir(app.dbDirectory);
            sz = size(dbInfo,1);
            numFiles = 0;

            for i = 1: 1: sz
                if (contains(lower(dbInfo(i).name),".mp3") || contains(lower(dbInfo(i).name),".wav"))
                   numFiles = numFiles + 1;
                end
            end 

            for k = 1:numFiles
               objArray(k) = cancion;
            end
            numFiles = 1;
            
            for i = 1: 1: sz
                if (contains(lower(dbInfo(i).name),".mp3") || contains(lower(dbInfo(i).name),".wav"))
                   objArray(numFiles).setSong(app.dbDirectory + "/" +  dbInfo(i).name);
                   numFiles = numFiles + 1;
                end
            end 

            app.cancionesDB = objArray;
        end
   

        function plotInputSong(app)
            t = 0:seconds(1/app.cancionInput.sampleFrec):seconds(app.cancionInput.duration);
            t = t(1:end-1);
            title(app.UIAxes, app.cancionInput.fileName);
            plot(app.UIAxes, t,app.cancionInput.signal);       
        end

        function plotSongMatch(app)
            t = 0:seconds(1/app.cancionMatch.sampleFrec):seconds(app.cancionMatch.duration);
            t = t(1:end-1);

            hold(app.UIAxes2,'on');
            title(app.UIAxes2, app.cancionMatch.fileName);
            plot(app.UIAxes2, t,app.cancionMatch.signal);  
            xregion(app.UIAxes2, app.desfaseTiempo , app.cancionInput.duration + app.desfaseTiempo, 'FaceColor', 'g'); 
            hold(app.UIAxes2,'off');
            
        end

        function [cor, lag] = getCor(app, cancionComp)
            [cor, lag] = xcorr(cancionComp.signal ,app.cancionInput.signal);
            return 
        end

            
        function timeDiff = getTimeDiff(app, cor, lag)
            [~,I] = max(abs(cor));
            SampleDiff = lag(I);
            timeDiff = SampleDiff/48000;
            return
        end

        function lookForMatch(app)
            correlationF = 0;
            lagF = 0;
            indxF = 0;

            for i = 1 : size(app.cancionesDB,2)
                [cor, lag] = getCor(app, app.cancionesDB(i));
                if (max(abs(cor)) > max(abs(correlationF)))
                    correlationF = cor;
                    lagF = lag;
                    indxF = i;
                end 
            end
    
            app.desfaseTiempo = getTimeDiff(app, correlationF, lagF)
            app.cancionMatch = app.cancionesDB(indxF);
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PontucancionButton
        function PontucancionButtonPushed(app, event)
            [filename, path] = uigetfile('*.mp3','*.wav');
            if (filename ~= 0)
                app.ShazameaEditField.Value = filename;
                app.cancionInput = cancion;
                app.cancionInput.setSong(filename);
                figure(app.UIFigure);
                app.AlakazameaButton.Visible = "on";
            end 
        end

        % Value changed function: ShazameaEditField
        function ShazameaEditFieldValueChanged(app, event)
            value = app.ShazameaEditField.Value;
            
        end

        % Button pushed function: AlakazameaButton
        function AlakazameaButtonPushed(app, event)
            app.setDatabase(); 
            app.plotInputSong();
            app.AlakazameaButton.Visible = "off";
            app.UIAxes.Visible = "on";

            app.lookForMatch();
            app.plotSongMatch();
            app.UIAxes2.Visible = "on";
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Tiempo')
            ylabel(app.UIAxes, 'Señal de audio')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'Tiempo')
            ylabel(app.UIAxes2, 'Señal de audio')
            app.UIAxes2.Visible = 'off';
            app.PontucancionButton = uibutton(app.UIFigure, 'push');
            app.PontucancionButton.ButtonPushedFcn = createCallbackFcn(app, @PontucancionButtonPushed, true);
            app.PontucancionButton.Text = 'Pon tu cancion!';
            app.ShazameaEditFieldLabel = uilabel(app.UIFigure);
            app.ShazameaEditFieldLabel.Text = 'Shazamea!';
            app.ShazameaEditField = uieditfield(app.UIFigure, 'text');
            app.ShazameaEditField.ValueChangedFcn = createCallbackFcn(app, @ShazameaEditFieldValueChanged, true);
            app.AlakazameaButton = uibutton(app.UIFigure, 'push');
            app.AlakazameaButton.ButtonPushedFcn = createCallbackFcn(app, @AlakazameaButtonPushed, true);
            app.AlakazameaButton.Visible = 'off';
            app.AlakazameaButton.Text = 'Alakazamea';
            app.ShazamAlakazamLabel = uilabel(app.UIFigure);
            app.ShazamAlakazamLabel.Text = 'S̶h̶a̶z̶a̶m̶  Alakazam';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = apParcial

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end